classdef A031 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField3                      matlab.ui.control.EditField
        Play3                           matlab.ui.control.Button
        LoadFileButton3                 matlab.ui.control.Button
        AuralizacinPanel                matlab.ui.container.Panel
        process                         matlab.ui.control.Label
        NombredelArchivoEditField       matlab.ui.control.EditField
        NombredelArchivoEditFieldLabel  matlab.ui.control.Label
        SeleccionarArchivoDropDown      matlab.ui.control.DropDown
        SeleccionarArchivoDropDownLabel  matlab.ui.control.Label
        Reproducir                      matlab.ui.control.Button
        GuardarButton                   matlab.ui.control.Button
        AuralizarButton                 matlab.ui.control.Button
        Archivo1Label_3                 matlab.ui.control.Label
        EditField2                      matlab.ui.control.EditField
        Archivo1Label_2                 matlab.ui.control.Label
        EditField1                      matlab.ui.control.EditField
        Archivo1Label                   matlab.ui.control.Label
        Play2                           matlab.ui.control.Button
        Play1                           matlab.ui.control.Button
        LoadFileButton2                 matlab.ui.control.Button
        LoadFileButton1                 matlab.ui.control.Button
    end

     properties (Access = private)
        FileData1 % Variable para almacenar los datos del archivo WAV 1
        FileData2 % Variable para almacenar los datos del archivo WAV 2
        FileData3 % Variable para almacenar los datos del archivo WAV 3
        FileNames % Lista de nombres de archivos para DropDown
        ImpulseData % Variable para almacenar los datos del archivo de impulso
        AuralizedSignal % Señal auralizada (resultado de la convolución)
        SamplingRate % Tasa de muestreo del archivo auralizado
     end
     
    methods (Access = private)
        % Función para cargar el archivo de impulso (impulso.wav)
        function loadImpulseFile(app)
            % Asegurarnos de cargar el archivo de impulso una sola vez
            if isempty(app.ImpulseData)
                [y_impulse, Fs_impulse] = audioread('impulso.wav');
                app.ImpulseData = struct('data', y_impulse, 'Fs', Fs_impulse);
            end
        end

      % Función para cargar el archivo WAV 1
        function LoadFile1(app, event)
            [file, path] = uigetfile('*.wav', 'Seleccione un archivo WAV');
            if isequal(file, 0)
                app.EditField1.Value = 'Ningún archivo seleccionado';
            else
                fullFileName = fullfile(path, file);
                [y, Fs] = audioread(fullFileName);  % Carga el archivo WAV
                app.FileData1 = struct('data', y, 'Fs', Fs);  % Almacena los datos
                app.EditField1.Value = file;  % Muestra el nombre del archivo cargado
                app.updateDropDown(file);
            end
        end
        
        % Función para cargar el archivo WAV 2
        function LoadFile2(app, event)
            [file, path] = uigetfile('*.wav', 'Seleccione un archivo WAV');
            if isequal(file, 0)
                app.EditField2.Value = 'Ningún archivo seleccionado';
            else
                fullFileName = fullfile(path, file);
                [y, Fs] = audioread(fullFileName);
                app.FileData2 = struct('data', y, 'Fs', Fs);
                app.EditField2.Value = file;
                app.updateDropDown(file);
            end
        end
        
        % Función para cargar el archivo WAV 3
        function LoadFile3(app, event)
            [file, path] = uigetfile('*.wav', 'Seleccione un archivo WAV');
            if isequal(file, 0)
                app.app.EditField3.Value = 'Ningún archivo seleccionado';
            else
                fullFileName = fullfile(path, file);
                [y, Fs] = audioread(fullFileName);
                app.FileData3 = struct('data', y, 'Fs', Fs);
                app.EditField3.Value = file;
                app.updateDropDown(file);
            end
        end

        % Función para actualizar el DropDown con el nombre del archivo
        function updateDropDown(app, fileName)
            % Añade el archivo cargado a la lista si no está
            if ~any(strcmp(app.FileNames, fileName))
                app.FileNames{end+1} = fileName;
            end
            % Actualiza el contenido del DropDown
            app.SeleccionarArchivoDropDown.Items = app.FileNames;
        end

        % Función para obtener los datos del archivo seleccionado en el dropdown
        function selectedData = getSelectedFileData(app)
            selectedFile = app.SeleccionarArchivoDropDown.Value;
            if strcmp(selectedFile, app.EditField1.Value)
                selectedData = app.FileData1;
            elseif strcmp(selectedFile, app.EditField2.Value)
                selectedData = app.FileData2;
            elseif strcmp(selectedFile, app.EditField3.Value)
                selectedData = app.FileData3;
            else
                selectedData = [];
            end
        end

        % Función para auralizar el archivo seleccionado con una sola respuesta al impulso
        function Auralizar(app, event)
            % Mostrar el mensaje "Auralizando..." en el Label process
            app.process.Text = 'Auralizando...';
            drawnow;  % Actualizar la interfaz gráfica antes de iniciar el proceso

            selectedData = app.getSelectedFileData();  % Obtener el archivo seleccionado
            if isempty(selectedData)
                uialert(app.UIFigure, 'No se ha seleccionado ningún archivo para auralizar.', 'Error');
                return;
            end
        
            app.loadImpulseFile();  % Cargar impulso.wav si no ha sido cargado
        
            % Convertir el archivo de audio a mono si es estéreo
            if size(selectedData.data, 2) > 1
                audioIn = mean(selectedData.data, 2);  % Promedio de los canales para convertir a mono
            else
                audioIn = selectedData.data;  % Ya es mono
            end
        
            % Convertir la respuesta de impulso a mono si es necesario
            if size(app.ImpulseData.data, 2) > 1
                impulseResponse = mean(app.ImpulseData.data, 2);  % Promedio de los canales
            else
                impulseResponse = app.ImpulseData.data;  % Ya es mono
            end
        
            % Ajustar las frecuencias de muestreo si no coinciden
            fs = selectedData.Fs;  % Frecuencia de muestreo del audio original
            fs_ir = app.ImpulseData.Fs;  % Frecuencia de muestreo del impulso
        
            % Si las frecuencias no coinciden, hacer resample
            if fs_ir ~= fs
                impulseResponse = resample(impulseResponse, fs, fs_ir);
            end
        
            % Realizar la convolución del audio con la respuesta de impulso
            audioOut = conv(audioIn, impulseResponse);
        
            % Normalizar el audio convolucionado
            audioOut = audioOut / max(abs(audioOut));
        
            % Almacenar la señal auralizada en la aplicación
            app.AuralizedSignal = audioOut;
        
            % Almacenar la frecuencia de muestreo para el guardado
            app.SamplingRate = fs;

            % Mostrar el mensaje "Proceso de auralización terminado" en el Label process
            app.process.Text = 'Proceso de auralización terminado';

        end

        % Función para guardar el archivo auralizado
        function GuardarAuralizado(app, event)
            if isempty(app.AuralizedSignal)
                uialert(app.UIFigure, 'No se ha auralizado ningún archivo.', 'Error');
                return;
            end

            % Obtener el nombre del archivo desde el campo de texto
            fileName = app.NombredelArchivoEditField.Value;
            if isempty(fileName)
                uialert(app.UIFigure, 'Por favor, ingrese un nombre para el archivo.', 'Error');
                return;
            end

            % Agregar extensión .wav si no está presente
            if ~endsWith(fileName, '.wav')
                fileName = strcat(fileName, '.wav');
            end

            % Guardar el archivo auralizado
            audiowrite(fileName, app.AuralizedSignal, app.SamplingRate);
            uialert(app.UIFigure, ['Archivo guardado como: ', fileName], 'Éxito');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton1
        function LoadFileButton1Pushed(app, event)
            LoadFile1(app, event);  % Llama a la función LoadFile1
        end

        % Button pushed function: LoadFileButton2
        function LoadFileButton2Pushed(app, event)
            LoadFile2(app, event);  % Llama a la función LoadFile2
        end

        % Button pushed function: LoadFileButton3
        function LoadFileButton3Pushed(app, event)
            LoadFile3(app, event);  % Llama a la función LoadFile3
        end

        % Value changed function: SeleccionarArchivoDropDown
        function SeleccionarArchivoDropDownValueChanged(app, event)
            selectedFile = app.SeleccionarArchivoDropDown.Value;
        end

        % Button pushed function: Play1
        function Play1ButtonPushed(app, event)
            if ~isempty(app.FileData1)
                sound(app.FileData1.data, app.FileData1.Fs);  % Reproduce el archivo 1
            else
                uialert(app.UIFigure, 'No se ha cargado ningún archivo para el botón 1.', 'Error');
            end
        end

        % Button pushed function: Play2
        function Play2ButtonPushed(app, event)
            if ~isempty(app.FileData2)
                sound(app.FileData2.data, app.FileData2.Fs);  % Reproduce el archivo 2
            else
                uialert(app.UIFigure, 'No se ha cargado ningún archivo para el botón 2.', 'Error');
            end
        end

        % Button pushed function: Play3
        function Play3ButtonPushed(app, event)
            if ~isempty(app.FileData3)
                sound(app.FileData3.data, app.FileData3.Fs);  % Reproduce el archivo 3
            else
                uialert(app.UIFigure, 'No se ha cargado ningún archivo para el botón 3.', 'Error');
            end
        end

        % Button pushed function: AuralizarButton
        function AuralizarButtonPushed(app, event)
            Auralizar(app, event);  % Llama a la función de auralización
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            GuardarAuralizado(app, event);  % Llama a la función GuardarAuralizado
        end

        % Button pushed function: Reproducir
        function ReproducirButtonPushed(app, event)
            if isempty(app.AuralizedSignal)
                uialert(app.UIFigure, 'No se ha auralizado ningún archivo para reproducir.', 'Error');
            else
                sound(app.AuralizedSignal, app.SamplingRate);  % Reproduce el archivo auralizado
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadFileButton1 = uibutton(app.UIFigure, 'push');
            app.LoadFileButton1.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButton1Pushed, true);
            app.LoadFileButton1.Tag = 'LoadFileButton1';
            app.LoadFileButton1.Text = 'Cargar Archivo 1';
            app.LoadFileButton2 = uibutton(app.UIFigure, 'push');
            app.LoadFileButton2.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButton2Pushed, true);
            app.LoadFileButton2.Tag = 'LoadFileButton1';
            app.LoadFileButton2.Text = 'Cargar Archivo 2';
            app.Play1 = uibutton(app.UIFigure, 'push');
            app.Play1.ButtonPushedFcn = createCallbackFcn(app, @Play1ButtonPushed, true);
            app.Play1.Icon = fullfile(pathToMLAPP, 'grabar1.png');
            app.Play1.Text = '';
            app.Play2 = uibutton(app.UIFigure, 'push');
            app.Play2.ButtonPushedFcn = createCallbackFcn(app, @Play2ButtonPushed, true);
            app.Play2.Icon = fullfile(pathToMLAPP, 'grabar2.png');
            app.Play2.Text = '';
            app.Archivo1Label = uilabel(app.UIFigure);
            app.Archivo1Label.Text = 'Archivo 1:';
            app.EditField1 = uieditfield(app.UIFigure, 'text');
            app.EditField1.Editable = 'off';
            app.EditField1.Value = 'Ningún archivo seleccionado';
            app.Archivo1Label_2 = uilabel(app.UIFigure);
            app.Archivo1Label_2.Text = 'Archivo 2:';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
            app.EditField2.Editable = 'off';
            app.EditField2.Value = 'Ningún archivo seleccionado';
            app.Archivo1Label_3 = uilabel(app.UIFigure);
            app.Archivo1Label_3.Text = 'Archivo 3:';
            app.AuralizacinPanel = uipanel(app.UIFigure);
            app.AuralizacinPanel.Title = 'Auralización';
            app.AuralizarButton = uibutton(app.AuralizacinPanel, 'push');
            app.AuralizarButton.ButtonPushedFcn = createCallbackFcn(app, @AuralizarButtonPushed, true);
            app.AuralizarButton.Text = 'Auralizar';
            app.GuardarButton = uibutton(app.AuralizacinPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.Reproducir = uibutton(app.AuralizacinPanel, 'push');
            app.Reproducir.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.Reproducir.Icon = fullfile(pathToMLAPP, 'repro.png');
            app.Reproducir.Text = '';
            app.SeleccionarArchivoDropDownLabel = uilabel(app.AuralizacinPanel);
            app.SeleccionarArchivoDropDownLabel.Text = 'Seleccionar Archivo';
            app.SeleccionarArchivoDropDown = uidropdown(app.AuralizacinPanel);
            app.SeleccionarArchivoDropDown.Items = {};
            app.SeleccionarArchivoDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccionarArchivoDropDownValueChanged, true);
            app.SeleccionarArchivoDropDown.Value = {};
            app.NombredelArchivoEditFieldLabel = uilabel(app.AuralizacinPanel);
            app.NombredelArchivoEditFieldLabel.Text = 'Nombre del Archivo';
            app.NombredelArchivoEditField = uieditfield(app.AuralizacinPanel, 'text');
            app.process = uilabel(app.AuralizacinPanel);
            app.process.Text = 'No se ha iniciado proceso de auralización';
            app.LoadFileButton3 = uibutton(app.UIFigure, 'push');
            app.LoadFileButton3.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButton3Pushed, true);
            app.LoadFileButton3.Tag = 'LoadFileButton1';
            app.LoadFileButton3.Text = 'Cargar Archivo 3';
            app.Play3 = uibutton(app.UIFigure, 'push');
            app.Play3.ButtonPushedFcn = createCallbackFcn(app, @Play3ButtonPushed, true);
            app.Play3.Icon = fullfile(pathToMLAPP, 'grabar3.png');
            app.Play3.Text = '';
            app.EditField3 = uieditfield(app.UIFigure, 'text');
            app.EditField3.Editable = 'off';
            app.EditField3.Value = 'Ningún archivo seleccionado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A031

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end